classdef interfata < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        Q2EditField       matlab.ui.control.NumericEditField
        Q2EditFieldLabel  matlab.ui.control.Label
        Q3EditField       matlab.ui.control.NumericEditField
        Q3EditFieldLabel  matlab.ui.control.Label
        Q1EditField       matlab.ui.control.NumericEditField
        Q1EditFieldLabel  matlab.ui.control.Label
        q3Slider          matlab.ui.control.Slider
        q2Slider          matlab.ui.control.Slider
        q1Slider          matlab.ui.control.Slider
        Button            matlab.ui.control.Button
        Slider_3Label     matlab.ui.control.Label
        Slider_2Label     matlab.ui.control.Label
        SliderLabel       matlab.ui.control.Label
        BratroboticLabel  matlab.ui.control.Label
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
   
    % Obține parametri din sliders
    global q1;
    global q2;
    global q3;
    q1 = app.q1Slider.Value * pi/180;
    q2 = app.q2Slider.Value * pi/180;
    q3 = app.q3Slider.Value;

    % Setează valorile în workspace-ul MATLAB
    
    grafice();
    % Rulează simularea


        end

        % Value changing function: q1Slider
        function q1SliderValueChanging(app, event)
            changingValue = event.Value;
            app.Q1EditField.Value=changingValue;
        end

        % Value changing function: q2Slider
        function q2SliderValueChanging(app, event)
            changingValue = event.Value;
            app.Q2EditField.Value=changingValue;
        end

        % Value changing function: q3Slider
        function q3SliderValueChanging(app, event)
            changingValue = event.Value;
            app.Q3EditField.Value=changingValue;
        end

        % Value changed function: Q1EditField
        function Q1EditFieldValueChanged(app, event)
            value = app.Q1EditField.Value;
            app.q1Slider.Value = value;
        end

        % Value changed function: Q2EditField
        function Q2EditFieldValueChanged(app, event)
            value = app.Q2EditField.Value;
            app.q2Slider.Value =value;
        end

        % Value changed function: Q3EditField
        function Q3EditFieldValueChanged(app, event)
            value = app.Q3EditField.Value;
            app.q3Slider.Value = value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.BratroboticLabel = uilabel(app.UIFigure);
            app.BratroboticLabel.Text = 'Brat robotic';
            app.SliderLabel = uilabel(app.UIFigure);
            app.SliderLabel.Text = 'Slider';
            app.Slider_2Label = uilabel(app.UIFigure);
            app.Slider_2Label.Text = 'Slider';
            app.Slider_3Label = uilabel(app.UIFigure);
            app.Slider_3Label.Text = 'Slider';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.q1Slider = uislider(app.UIFigure);
            app.q1Slider.Limits = [-180 180];
            app.q1Slider.ValueChangingFcn = createCallbackFcn(app, @q1SliderValueChanging, true);
            app.q2Slider = uislider(app.UIFigure);
            app.q2Slider.Limits = [-180 180];
            app.q2Slider.ValueChangingFcn = createCallbackFcn(app, @q2SliderValueChanging, true);
            app.q3Slider = uislider(app.UIFigure);
            app.q3Slider.Limits = [-1 1];
            app.q3Slider.ValueChangingFcn = createCallbackFcn(app, @q3SliderValueChanging, true);
            app.Q1EditFieldLabel = uilabel(app.UIFigure);
            app.Q1EditFieldLabel.Text = 'Q1';
            app.Q1EditField = uieditfield(app.UIFigure, 'numeric');
            app.Q1EditField.Limits = [-180 180];
            app.Q1EditField.ValueChangedFcn = createCallbackFcn(app, @Q1EditFieldValueChanged, true);
            app.Q3EditFieldLabel = uilabel(app.UIFigure);
            app.Q3EditFieldLabel.Text = 'Q3';
            app.Q3EditField = uieditfield(app.UIFigure, 'numeric');
            app.Q3EditField.Limits = [-180 180];
            app.Q3EditField.ValueChangedFcn = createCallbackFcn(app, @Q3EditFieldValueChanged, true);
            app.Q2EditFieldLabel = uilabel(app.UIFigure);
            app.Q2EditFieldLabel.Text = 'Q2';
            app.Q2EditField = uieditfield(app.UIFigure, 'numeric');
            app.Q2EditField.Limits = [-180 180];
            app.Q2EditField.ValueChangedFcn = createCallbackFcn(app, @Q2EditFieldValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = interfata

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end